classdef PN_JUNCTION_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        calculationTab          matlab.ui.container.Tab
        Image                   matlab.ui.control.Image
        Lamp                    matlab.ui.control.Lamp
        EditField               matlab.ui.control.EditField
        npoEditField            matlab.ui.control.NumericEditField
        npoEditFieldLabel       matlab.ui.control.Label
        pnoEditField            matlab.ui.control.NumericEditField
        pnoEditFieldLabel       matlab.ui.control.Label
        xpoEditField            matlab.ui.control.NumericEditField
        xpoEditFieldLabel       matlab.ui.control.Label
        xnoEditField            matlab.ui.control.NumericEditField
        xnoEditFieldLabel       matlab.ui.control.Label
        E_maxEditField          matlab.ui.control.NumericEditField
        E_maxEditFieldLabel     matlab.ui.control.Label
        wEditField              matlab.ui.control.NumericEditField
        wEditFieldLabel         matlab.ui.control.Label
        vbiEditField            matlab.ui.control.NumericEditField
        vbiEditFieldLabel       matlab.ui.control.Label
        calculateButton         matlab.ui.control.Button
        NAEditField_2           matlab.ui.control.NumericEditField
        NAEditField_2Label      matlab.ui.control.Label
        NAEditField             matlab.ui.control.EditField
        NAEditFieldLabel        matlab.ui.control.Label
        NDEditField_2           matlab.ui.control.NumericEditField
        NDEditField_2Label      matlab.ui.control.Label
        NDEditField             matlab.ui.control.EditField
        NDEditFieldLabel        matlab.ui.control.Label
        pn_junctionthermalequilibriumLabel  matlab.ui.control.Label
        npgraphsTab             matlab.ui.container.Tab
        fig_4                   matlab.ui.control.UIAxes
        fig                     matlab.ui.control.UIAxes
        EVgraphsTab             matlab.ui.container.Tab
        fig_3                   matlab.ui.control.UIAxes
        fig_2                   matlab.ui.control.UIAxes
        animationpnJunctionTab  matlab.ui.container.Tab
        RunButton_4             matlab.ui.control.Button
        animation               matlab.ui.control.UIAxes
        animationthermalTab     matlab.ui.container.Tab
        RunButton               matlab.ui.control.Button
        anime_thermal           matlab.ui.control.UIAxes
        animationReverseTab     matlab.ui.container.Tab
        RunButton_2             matlab.ui.control.Button
        anime_Reverse           matlab.ui.control.UIAxes
        animationForwardTab     matlab.ui.container.Tab
        RunButton_3             matlab.ui.control.Button
        anime_Forward           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            q_c=1.6*10^-19;
           epsilon_si=11.8*8.854*10^-14;
           T_k=300;
           K=1.38*10^-23;
           ni=1.5*10^10;
           x=linspace(-0.0001,0.0001,10000);

            ND=app.NDEditField_2.Value;
            NA=app.NAEditField_2.Value;
             if(ND<=10^14&&NA<=10^6)
                 app.EditField.Value='ERROR (in ND & NA values)';
                app.Lamp.Color='r'
                 app.vbiEditField.Value=0;
                app.wEditField.Value=0;
                app.xpoEditField.Value=0;
                app.xnoEditField.Value=0;
                app.npoEditField.Value=0;
                app.pnoEditField.Value=0;
                app.E_maxEditField.Value=0;
                 cla(app.fig);
                cla(app.fig_4);
                cla(app.fig_3);
                cla(app.fig_2);

        elseif(ND<=10^14)
                app.EditField.Value='ERROR (in ND value)';
                app.Lamp.Color='r'
                app.vbiEditField.Value=0;
                app.wEditField.Value=0;
                app.xpoEditField.Value=0;
                app.xnoEditField.Value=0;
                app.npoEditField.Value=0;
                app.pnoEditField.Value=0;
                app.E_maxEditField.Value=0;
                cla(app.fig);
                cla(app.fig_4);
                cla(app.fig_3);
                cla(app.fig_2);


            elseif(NA<=10^6)
                 app.EditField.Value='ERROR (in NA value)';
                app.Lamp.Color='r'
                 app.vbiEditField.Value=0;
                app.wEditField.Value=0;
                app.xpoEditField.Value=0;
                app.xnoEditField.Value=0;
                app.npoEditField.Value=0;
                app.pnoEditField.Value=0;
                app.E_maxEditField.Value=0;
                 cla(app.fig);
                cla(app.fig_4);
                cla(app.fig_3);
                cla(app.fig_2);


            else
                 app.EditField.Value='';
                app.Lamp.Color='w'
            %% built_in potential
        vbi=(K*T_k/q_c)*log(NA*ND/(ni^2)) ;
       app.vbiEditField.Value=vbi;

       %% width 
    w=sqrt((2*epsilon_si/q_c)*vbi*((NA+ND)/(NA*ND)));
    xpo=(ND*w)/(ND+NA);
    xno=w-xpo;

      app.wEditField.Value=w;
      app.xpoEditField.Value=xpo;
      app.xnoEditField.Value=xno;

%% imp_eq.;
    npo=ND*exp((-q_c*vbi)/(K*T_k));
    pno=NA*exp((-q_c*vbi)/(K*T_k));
     app.npoEditField.Value=npo;
     app.pnoEditField.Value=pno;


        %% electric_field
        E_max=q_c*ND*xno/epsilon_si;
        app.E_maxEditField.Value=E_max;

            %% concentration n&p @thermal equ.

x_1=[-xpo -(xpo+10^-4)];
x_2=[(xno+10^-4)  xno];

plot(app.fig,x_2,[ND ND],'--k');
hold (app.fig, "on")
 grid( app.fig,"on")
plot(app.fig,x_1,[NA NA],'-- r');
plot(app.fig,x_1,[npo npo],'-- k');
plot(app.fig,x_2,[pno pno],'--r');

  x_3=[xno -xpo];
  y_1=[ND npo];
  y_2=[pno NA];

  plot(app.fig,x_3,y_1,'m:');
  plot(app.fig,x_3,y_2,'m:');


 %% v
 
 hold(app.fig_2,"on") 
 grid( app.fig_2,"on")
x1_v=[xno+10^-4 xno];
x2_v=[-xpo -(xpo+10^-4)];
x3_v=[xno -xpo];

plot(app.fig_2,x1_v,[vbi vbi],'r-')
plot(app.fig_2,x2_v,[0 0],'r-')
plot(app.fig_2,x3_v,[vbi 0],'r-')

%% electric field
 hold(app.fig_3,"on") 
 grid( app.fig_3,"on")
 E_max=q_c*ND*xno/epsilon_si
 x1=[xno 0 -xpo];
 y1=[0 -E_max 0];
 plot(app.fig_3,x1,y1)
 

%% Row
 hold(app.fig_4,"on") 
 grid( app.fig_4,"on")
xr1=[xno 0];
xr2=[0 -xpo];
yr1= [0 ND];
yr2=[-NA 0];
plot(app.fig_4,xr1,[ND ND],'-b');

plot(app.fig_4,xr2,[-NA -NA],'r');
plot(app.fig_4,[0 0],yr1,'b');
plot(app.fig_4,[xno xno],yr1,'b');
plot(app.fig_4,[0 0],yr2,'r');
plot(app.fig_4,[-xpo -xpo],yr2,'r');
plot(app.fig_4,[12*10^-4 -12*10^-4],[0 0],'k-');


            end


        end

        % Button down function: fig
        function figButtonDown(app, event)
            
        end

        % Button down function: anime_thermal
        function anime_thermalButtonDown(app, event)


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                %% plot
hold(app.anime_thermal,"on") 
 grid(app.anime_thermal,"on")

x_v1=[10 2];
x_v2=[-2 -10];
x_v=[2 -2];
y1_v=[4.5 5.5];
y2_v=[3 4];
y3_v=[3.75 4.75];
%Ec&Ev
plot(app.anime_thermal,x_v1,[4.5 4.5],'b')
plot(app.anime_thermal,x_v2,[5.5 5.5],'b')
plot(app.anime_thermal,x_v,y1_v,'b')
plot(app.anime_thermal,x_v,y2_v,'b')
plot(app.anime_thermal,x_v1,[3 3],'b')
plot(app.anime_thermal,x_v2,[4 4],'b')

% Ei
plot(app.anime_thermal,x_v1,[3.75 3.75],' :k')
plot(app.anime_thermal,x_v2,[4.75 4.75],' :k')
plot(app.anime_thermal,x_v,y3_v,' :k')

%Ef
plot(app.anime_thermal,[10 -10],[4.25 4.25],':r')

%% electron and holes
%electron
 plot(app.anime_thermal,3,5,'KO',MarkerFaceColor ='K')
 plot(app.anime_thermal,5,5,'KO',MarkerFaceColor ='K')
 plot(app.anime_thermal,7,5,'KO',MarkerFaceColor ='K')
 plot(app.anime_thermal,4,5.5,'KO',MarkerFaceColor ='K')
 plot(app.anime_thermal,6,5.5,'KO',MarkerFaceColor ='K')
 plot(app.anime_thermal,5,6,'KO',MarkerFaceColor ='K')
 plot(app.anime_thermal,-5,6,'KO',MarkerFaceColor ='K')
% holes
plot(app.anime_thermal,-3,3.5,'rO')
plot(app.anime_thermal,-5,3.5,'rO') 
plot(app.anime_thermal,-7,3.5,'rO')
plot(app.anime_thermal,-4,3,'rO')
plot(app.anime_thermal,-6,3,'rO')
plot(app.anime_thermal,-5,2.25,'rO')
plot(app.anime_thermal,5,2.25,'rO')
%% move electron and holes


obj = VideoWriter("My_video_4");
open(obj)
x1=linspace(5,0,6);
x2=linspace(-5,-1,6);
x3=linspace(-5,-1,6);
x4=linspace(5,0,6);
y1=linspace(6,6,10);
y2=linspace(2.25,2.25,10);

hold (app.anime_thermal,"on")
for i = 2:length(x1)

       
        plot(app.anime_thermal,x1(1:i), y1(1:i),'KO',MarkerFaceColor ='K')
        plot(app.anime_thermal,5, 6,'wO')
        plot(app.anime_thermal,x1(1:i-1), y1(1:i-1),'wO',MarkerFaceColor ='w')

         plot(app.anime_thermal,x2(1:i), y1(1:i),'KO',MarkerFaceColor ='K')
       plot(app.anime_thermal,x2(1:i-1), y1(1:i-1),'wO',MarkerFaceColor ='w')

        
        plot(app.anime_thermal,x3(1:i), y2(1:i),'rO')
        plot(app.anime_thermal,x3(1:i-1), y2(1:i-1),'wO',MarkerFaceColor ='w')

        plot(app.anime_thermal,x4(1:i), y2(1:i),'rO')
       plot(app.anime_thermal,x4(1:i-1), y2(1:i-1),'wO',MarkerFaceColor ='w')
        
        M(i) = getframe(app.anime_thermal);
     
        pause(0.1)
 
end
plot(app.anime_thermal,0,6,'wO',MarkerFaceColor='w')
plot(app.anime_thermal,-1,6,'wO',MarkerFaceColor='w')
plot(app.anime_thermal,-1,2.25,'wO',MarkerFaceColor='w')
plot(app.anime_thermal,0,2.25,'wO',MarkerFaceColor='w')


        end

        % Button down function: anime_Reverse
        function anime_ReverseButtonDown(app, event)
          
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
              %% plot gragh
hold(app.anime_Reverse,"on") 
 grid(app.anime_Reverse,"on")
x_v1=[7 1];
x_v2=[-1 -7];
x_v=[1 -1];
y1_v=[3 6];
y2_v=[1 4];
y3_v=[2 5];
plot(app.anime_Reverse,x_v1,[3 3],'b')
plot(app.anime_Reverse,x_v2,[6 6],'b')
plot(app.anime_Reverse,x_v,y1_v,'b')
plot(app.anime_Reverse,x_v,y2_v,'b')
plot(app.anime_Reverse,x_v1,[1 1],'b')
plot(app.anime_Reverse,x_v2,[4 4],'b')
plot(app.anime_Reverse,x_v1,[2 2],' :r')
plot(app.anime_Reverse,x_v2,[5 5],' :r')
plot(app.anime_Reverse,x_v,y3_v,' :r')
plot(app.anime_Reverse,x_v1,[2.75 2.75],' k')
plot(app.anime_Reverse,x_v2,[4.25 4.25],' k')

%% electron&holes
plot(app.anime_Reverse,2,4,'KO',MarkerFaceColor ='K')
plot(app.anime_Reverse,4,4,'KO',MarkerFaceColor ='K')
plot(app.anime_Reverse,3,5,'KO',MarkerFaceColor ='K')
plot(app.anime_Reverse,-4,7,'KO',MarkerFaceColor ='K')

plot(app.anime_Reverse,4,0,'rO')
plot(app.anime_Reverse,-6,3,'rO') 
plot(app.anime_Reverse,-4,3,'rO')
plot(app.anime_Reverse,-5,2,'rO')
%% move electron&holes

obj = VideoWriter("My_video_4");
open(obj)
x1=linspace(2,0.5,7);
x2=linspace(4,0.5,7);
x3=linspace(3,0,7);
y1=linspace(4,4,10);
y3=linspace(5,5,10);

x4=linspace(-4,-0.5,7);
x5=linspace(-6,-0.5,7);
x6=linspace(-5,0,7);
y4=linspace(3,3,10);
y6=linspace(2,2,10);

x7=linspace(-4,0,7)
x8=linspace(4,0,7)
y7=linspace(7,7,10)
y8=linspace(0,0,10)

hold (app.anime_Reverse,"on")
for i = 1:length(x1)
    plot(app.anime_Reverse,x1(1:i), y1(1:i),'KO',MarkerFaceColor ='K')
    plot(app.anime_Reverse,x1(1:i-1), y1(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Reverse,x2(1:i), y1(1:i),'KO',MarkerFaceColor ='K')
    plot(app.anime_Reverse,x2(1:i-1), y1(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Reverse,x3(1:i), y3(1:i),'KO',MarkerFaceColor ='K')
    plot(app.anime_Reverse,x3(1:i-1), y3(1:i-1),'wO',MarkerFaceColor ='w')

    plot(app.anime_Reverse,x4(1:i), y4(1:i),'rO')
    plot(app.anime_Reverse,x4(1:i-1), y4(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Reverse,x5(1:i), y4(1:i),'rO')
    plot(app.anime_Reverse,x5(1:i-1), y4(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Reverse,x6(1:i), y6(1:i),'rO')
    plot(app.anime_Reverse,x6(1:i-1), y6(1:i-1),'wO',MarkerFaceColor ='w')

     plot(app.anime_Reverse,x7(1:i), y7(1:i),'KO',MarkerFaceColor ='K')
     plot(app.anime_Reverse,x7(1:i-1), y7(1:i-1),'wO',MarkerFaceColor ='w')
     plot(app.anime_Reverse,x8(1:i), y8(1:i),'rO')
     plot(app.anime_Reverse,x8(1:i-1), y8(1:i-1),'wO',MarkerFaceColor ='w')
    M(i) = getframe(app.anime_Reverse);
    pause(0.1)
end

%% delete 
plot(app.anime_Reverse,0.5,4,'wO',MarkerFaceColor ='w')
plot(app.anime_Reverse,0,5,'wO',MarkerFaceColor ='w')
plot(app.anime_Reverse,0,2,'wO')
plot(app.anime_Reverse,-0.5,3,'wO')
plot(app.anime_Reverse,0,0,'wO')
plot(app.anime_Reverse,0,7,'wO',MarkerFaceColor ='w')
%% new electron&holes 
plot(app.anime_Reverse,2,4,'KO',MarkerFaceColor ='K')
plot(app.anime_Reverse,4,4,'KO',MarkerFaceColor ='K')
plot(app.anime_Reverse,3,5,'KO',MarkerFaceColor ='K')
plot(app.anime_Reverse,-4,7,'KO',MarkerFaceColor ='K')

plot(app.anime_Reverse,4,0,'rO')
plot(app.anime_Reverse,-6,3,'rO') 
plot(app.anime_Reverse,-4,3,'rO')
plot(app.anime_Reverse,-5,2,'rO')


        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
hold(app.anime_Forward,"on") 
grid(app.anime_Forward,"on")

x_v1=[10 2];
x_v2=[-2 -10];
x_v=[2 -2];
y1_v=[4.5 5];
y2_v=[3.5 4];
y3_v=[4 4.5];
% Ec&Ev
plot(app.anime_Forward,x_v1,[4.5 4.5],'b')
plot(app.anime_Forward,x_v2,[5 5],'b')
plot(app.anime_Forward,x_v,y1_v,'b')
plot(app.anime_Forward,x_v,y2_v,'b')
plot(app.anime_Forward,x_v1,[3.5 3.5],'b')
plot(app.anime_Forward,x_v2,[4 4],'b')
% Ei
plot(app.anime_Forward,x_v1,[4 4],' :k')
plot(app.anime_Forward,x_v2,[4.5 4.5],' :k')
plot(app.anime_Forward,x_v,y3_v,' :k')
% Ef 
plot(app.anime_Forward,x_v1,[4.3 4.3],':r')
plot(app.anime_Forward,x_v2,[4.2 4.2],':r')

%% electron&holes

%electron
 plot(app.anime_Forward,3,4.75,'KO',MarkerFaceColor ='K')
 plot(app.anime_Forward,5,4.75,'KO',MarkerFaceColor ='K')
 plot(app.anime_Forward,7,4.75,'KO',MarkerFaceColor ='K')
 plot(app.anime_Forward,4,5.5,'KO',MarkerFaceColor ='K')
 plot(app.anime_Forward,6,5.5,'KO',MarkerFaceColor ='K')
 plot(app.anime_Forward,5,6,'KO',MarkerFaceColor ='K')
 plot(app.anime_Forward,-5,5.5,'KO',MarkerFaceColor ='K')
% holes
plot(app.anime_Forward,-3,3.75,'rO')
plot(app.anime_Forward,-5,3.75,'rO') 
plot(app.anime_Forward,-7,3.75,'rO')
plot(app.anime_Forward,-4,3,'rO')
plot(app.anime_Forward,-6,3,'rO')
plot(app.anime_Forward,-5,2.25,'rO')
plot(app.anime_Forward,5,3,'rO') 

%% move electron&holes

obj = VideoWriter("My_video_4");
open(obj)
x1=linspace(4,-1,6);
x2=linspace(6,0,6);
x3=linspace(5,-0.5,6);
x4=linspace(-5,-2,6);
y1=linspace(5.5,5.5,10);
y3=linspace(6,6,10);
x5=linspace(-4,-.5,6);
x6=linspace(-6,-1.5,6);
x7=linspace(-5,-1,6);
x8=linspace(5,0.5,6)
y5=linspace(3,3,10);
y7=linspace(2.25,2.25,10);


for i = 1:length(x1)
     plot(app.anime_Forward,x1(1:i), y1(1:i),'KO',MarkerFaceColor ='K')
     plot(app.anime_Forward,x1(1:i-1), y1(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Forward,x2(1:i), y1(1:i),'KO',MarkerFaceColor ='K')
    plot(app.anime_Forward,x2(1:i-1), y1(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Forward,x3(1:i), y3(1:i),'KO',MarkerFaceColor ='K')
    plot(app.anime_Forward,x3(1:i-1), y3(1:i-1),'wO',MarkerFaceColor ='w')

    plot(app.anime_Forward,x4(1:i), y1(1:i),'KO',MarkerFaceColor ='K')
    plot(app.anime_Forward,x4(1:i-1), y1(1:i-1),'wO',MarkerFaceColor ='w')

    plot(app.anime_Forward,x5(1:i), y5(1:i),'rO')
    plot(app.anime_Forward,x5(1:i-1), y5(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Forward,x6(1:i), y5(1:i),'rO')
    plot(app.anime_Forward,x6(1:i-1), y5(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Forward,x7(1:i), y7(1:i),'rO')
    plot(app.anime_Forward,x7(1:i-1), y7(1:i-1),'wO',MarkerFaceColor ='w')
    plot(app.anime_Forward,x8(1:i), y5(1:i),'rO')
    plot(app.anime_Forward,x8(1:i-1), y5(1:i-1),'wO',MarkerFaceColor ='w')

    M(i) = getframe(app.anime_Forward);
    pause(0.1)
end
        end

        % Button pushed function: RunButton_4
        function RunButton_4Pushed(app, event)
hold (app.animation,"on")
grid (app.animation,"on")

%% electron&holes
obj = VideoWriter("My_video_4");
open(obj)
for j=1:3
for i = 1:4

    plot(app.animation,i,j,'KO',MarkerFaceColor ='K')
    hold (app.animation,"on")
    M(i) = getframe(app.animation);
    pause(0.01)
    plot(app.animation,-i,j,'rO')
    hold (app.animation,"on")
    M(i) = getframe(app.animation);
    pause(0.01)
end
end

%% netural region

obj = VideoWriter("My_video_4");
open(obj)
x1=linspace(1,-1,10);
x2=linspace(-1,1,10);
x3=linspace(1,-1,10);
y1=linspace(3,3,10);
y2=linspace(2,2,10);
y3=linspace(1,1,10);
% 

for i = 1:length(x1)
    plot(app.animation,x1(1:i), y1(1:i),'m.')
    plot(app.animation,x2(1:i), y2(1:i),'m.')
    plot(app.animation,x3(1:i), y3(1:i),'m.')
  
    M(i) = getframe(app.animation);
    pause(0.1)
end

plot(app.animation,1,3,'bo',MarkerFaceColor ='b')

plot(app.animation,-1,3,'bo',MarkerFaceColor ='b')
plot(app.animation,-1,2,'bo',MarkerFaceColor ='b')
plot(app.animation,1,2,'bo',MarkerFaceColor ='b')
plot(app.animation,1,1,'bo',MarkerFaceColor ='b')
plot(app.animation,-1,1,'bo',MarkerFaceColor ='b')

% new column


obj = VideoWriter("My_video_4");
open(obj)
x1=linspace(2,-2,10);
x2=linspace(-2,2,10);
x3=linspace(2,-2,10);
% 

for i = 1:length(x1)
    plot(app.animation,x1(1:i), y1(1:i),'m.')
    plot(app.animation,x2(1:i), y2(1:i),'m.')
    plot(app.animation,x3(1:i), y3(1:i),'m.')
  
    M(i) = getframe(app.animation);
    pause(0.1)
end

plot(app.animation,-2,1,'bo',MarkerFaceColor ='b')
hold (app.animation,"on")
plot(app.animation,2,3,'bo',MarkerFaceColor ='b')
plot(app.animation,-2,3,'bo',MarkerFaceColor ='b')
plot(app.animation,-2,2,'bo',MarkerFaceColor ='b')
plot(app.animation,2,2,'bo',MarkerFaceColor ='b')
plot(app.animation,2,1,'bo',MarkerFaceColor ='b')
%%
a_x1= 2.5;
a_x2=-2.5;
a_y=[0 4 ];
plot(app.animation,[a_x1 a_x1],a_y,':m')
plot(app.animation,[a_x2 a_x2],a_y,':m')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.calculationTab = uitab(app.TabGroup);
            app.calculationTab.Title = 'calculation';
            app.pn_junctionthermalequilibriumLabel = uilabel(app.calculationTab);
            app.pn_junctionthermalequilibriumLabel.Text = '   pn_junction@thermal equilibrium ';
            app.NDEditFieldLabel = uilabel(app.calculationTab);
            app.NDEditFieldLabel.Text = 'ND';
            app.NDEditField = uieditfield(app.calculationTab, 'text');
            app.NDEditField.Value = 'Enter ND value';
            app.NDEditField_2Label = uilabel(app.calculationTab);
            app.NDEditField_2Label.Text = 'ND';
            app.NDEditField_2 = uieditfield(app.calculationTab, 'numeric');
            app.NAEditFieldLabel = uilabel(app.calculationTab);
            app.NAEditFieldLabel.Text = 'NA';
            app.NAEditField = uieditfield(app.calculationTab, 'text');
            app.NAEditField.Value = 'Enter NA value';
            app.NAEditField_2Label = uilabel(app.calculationTab);
            app.NAEditField_2Label.Text = 'NA';
            app.NAEditField_2 = uieditfield(app.calculationTab, 'numeric');
            app.calculateButton = uibutton(app.calculationTab, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.vbiEditFieldLabel = uilabel(app.calculationTab);
            app.vbiEditFieldLabel.Text = 'vbi';
            app.vbiEditField = uieditfield(app.calculationTab, 'numeric');
            app.wEditFieldLabel = uilabel(app.calculationTab);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.calculationTab, 'numeric');
            app.E_maxEditFieldLabel = uilabel(app.calculationTab);
            app.E_maxEditFieldLabel.Text = 'E_max';
            app.E_maxEditField = uieditfield(app.calculationTab, 'numeric');
            app.xnoEditFieldLabel = uilabel(app.calculationTab);
            app.xnoEditFieldLabel.Text = 'xno';
            app.xnoEditField = uieditfield(app.calculationTab, 'numeric');
            app.xpoEditFieldLabel = uilabel(app.calculationTab);
            app.xpoEditFieldLabel.Text = 'xpo';
            app.xpoEditField = uieditfield(app.calculationTab, 'numeric');
            app.pnoEditFieldLabel = uilabel(app.calculationTab);
            app.pnoEditFieldLabel.Text = 'pno';
            app.pnoEditField = uieditfield(app.calculationTab, 'numeric');
            app.npoEditFieldLabel = uilabel(app.calculationTab);
            app.npoEditFieldLabel.Text = 'npo';
            app.npoEditField = uieditfield(app.calculationTab, 'numeric');
            app.EditField = uieditfield(app.calculationTab, 'text');
            app.Lamp = uilamp(app.calculationTab);
            app.Image = uiimage(app.calculationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Capture.PNG');
            app.npgraphsTab = uitab(app.TabGroup);
            app.npgraphsTab.Title = 'n&p graphs';
            app.fig = uiaxes(app.npgraphsTab);
            title(app.fig, 'n&p concentration')
            xlabel(app.fig, 'X(cm)')
            ylabel(app.fig, 'concentration(cm^-3)')
            zlabel(app.fig, 'Z')
            app.fig.ButtonDownFcn = createCallbackFcn(app, @figButtonDown, true);
            app.fig_4 = uiaxes(app.npgraphsTab);
            title(app.fig_4, 'charge-density')
            xlabel(app.fig_4, 'X(cm)')
            ylabel(app.fig_4, 'Row')
            zlabel(app.fig_4, 'Z')
            app.EVgraphsTab = uitab(app.TabGroup);
            app.EVgraphsTab.Title = 'E&V graphs';
            app.fig_2 = uiaxes(app.EVgraphsTab);
            title(app.fig_2, 'potential (V)')
            xlabel(app.fig_2, 'X(cm)')
            ylabel(app.fig_2, 'potential(V)')
            zlabel(app.fig_2, 'Z')
            app.fig_3 = uiaxes(app.EVgraphsTab);
            title(app.fig_3, 'electric-field')
            xlabel(app.fig_3, 'X(cm)')
            ylabel(app.fig_3, 'E(v/cm)')
            zlabel(app.fig_3, 'Z')
            app.animationpnJunctionTab = uitab(app.TabGroup);
            app.animationpnJunctionTab.Title = 'animation pn-Junction';
            app.animation = uiaxes(app.animationpnJunctionTab);
            title(app.animation, 'pn-Junction')
            xlabel(app.animation, 'X')
            ylabel(app.animation, 'n&p')
            zlabel(app.animation, 'Z')
            app.RunButton_4 = uibutton(app.animationpnJunctionTab, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Text = 'Run';
            app.animationthermalTab = uitab(app.TabGroup);
            app.animationthermalTab.Title = 'animation@thermal';
            app.anime_thermal = uiaxes(app.animationthermalTab);
            title(app.anime_thermal, 'Energy Band Diagram @thermal equilibrium')
            xlabel(app.anime_thermal, 'X')
            ylabel(app.anime_thermal, 'Y')
            zlabel(app.anime_thermal, 'Z')
            app.anime_thermal.ButtonDownFcn = createCallbackFcn(app, @anime_thermalButtonDown, true);
            app.RunButton = uibutton(app.animationthermalTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.animationReverseTab = uitab(app.TabGroup);
            app.animationReverseTab.Title = 'animation@Reverse';
            app.anime_Reverse = uiaxes(app.animationReverseTab);
            title(app.anime_Reverse, 'Energy Band Diagram @ Reverse Bias')
            xlabel(app.anime_Reverse, 'X')
            ylabel(app.anime_Reverse, 'Y')
            zlabel(app.anime_Reverse, 'Z')
            app.anime_Reverse.ButtonDownFcn = createCallbackFcn(app, @anime_ReverseButtonDown, true);
            app.RunButton_2 = uibutton(app.animationReverseTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.animationForwardTab = uitab(app.TabGroup);
            app.animationForwardTab.Title = 'animation@Forward';
            app.anime_Forward = uiaxes(app.animationForwardTab);
            title(app.anime_Forward, 'Energy Band Diagram @ Forward Bias')
            xlabel(app.anime_Forward, 'X')
            ylabel(app.anime_Forward, 'Y')
            zlabel(app.anime_Forward, 'Z')
            app.RunButton_3 = uibutton(app.animationForwardTab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PN_JUNCTION_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end